classdef motoknob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotoTrakKnobUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MainPanel                     matlab.ui.container.Panel
        MotoTrakKnobTaskLabel         matlab.ui.control.Label
        RatIDEditFieldLabel           matlab.ui.control.Label
        rat_id                        matlab.ui.control.EditField
        SavelocationparentfolderEditFieldLabel  matlab.ui.control.Label
        save_dir                      matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        DurationminEditFieldLabel     matlab.ui.control.Label
        duration                      matlab.ui.control.NumericEditField
        HitthreshdegEditFieldLabel    matlab.ui.control.Label
        hit_thresh                    matlab.ui.control.NumericEditField
        adaptiveLabel                 matlab.ui.control.Label
        adapt_hit_thresh              matlab.ui.control.CheckBox
        minLabel                      matlab.ui.control.Label
        maxLabel                      matlab.ui.control.Label
        hit_thresh_min                matlab.ui.control.NumericEditField
        hit_thresh_max                matlab.ui.control.NumericEditField
        adapt_hit_ceil                matlab.ui.control.CheckBox
        hit_ceil_min                  matlab.ui.control.NumericEditField
        hit_ceil_max                  matlab.ui.control.NumericEditField
        HitceilingdegEditFieldLabel   matlab.ui.control.Label
        hit_ceil                      matlab.ui.control.NumericEditField
        HitwindowsEditFieldLabel      matlab.ui.control.Label
        hit_window                    matlab.ui.control.NumericEditField
        adapt_hold_time               matlab.ui.control.CheckBox
        hold_time_min                 matlab.ui.control.NumericEditField
        hold_time_max                 matlab.ui.control.NumericEditField
        HoldtimesEditFieldLabel       matlab.ui.control.Label
        hold_time                     matlab.ui.control.NumericEditField
        KnobposcmEditFieldLabel       matlab.ui.control.Label
        knob_pos                      matlab.ui.control.NumericEditField
        InitthreshdegEditFieldLabel   matlab.ui.control.Label
        init_thresh                   matlab.ui.control.NumericEditField
        STARTButton                   matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        FEEDButton                    matlab.ui.control.Button
        ConnectMotoTrakButton         matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        RetractKnobButton             matlab.ui.control.StateButton
        RightPanel                    matlab.ui.container.Panel
        KnobAngleAxes                 matlab.ui.control.UIAxes
        PelletsdeliveredLabel         matlab.ui.control.Label
        PelletsdeliveredCounterLabel  matlab.ui.control.Label
        TimeelapsedLabel              matlab.ui.control.Label
        TimeelapsedCounterLabel       matlab.ui.control.Label
        NumTrialsLabel                matlab.ui.control.Label
        NumRewardsLabel               matlab.ui.control.Label
        NumTrialsCounterLabel         matlab.ui.control.Label
        NumRewardsCounterLabel        matlab.ui.control.Label
        MedianPeakLabel               matlab.ui.control.Label
        MedianPeakValueLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        moto = []       ;        % mototrak object
        hit_line        ;        %= line(app.KnobAngleAxes,0,0,'--r');
        init_line       ;        %= line(app.KnobAngleAxes,0,0,'--b');
        start_line      ;        %= line(app.KnobAngleAxes,0,0,'Color',[.5 .5 .5]);
        hit_window_line ;        %= line(app.KnobAngleAxes,0,0,'Color',[.5 .5 .5]);
        angle_line      ;        %= line(app.KnobAngleAxes,0,0,'Color','b','LineWidth',2);
        stop_session    ;        % flag to stop the session when STOP button is clicked
        Median_peak     = 0;     % ongoing median of angle peak for last 10 trials
        reward_sound    = 8000;  % 8kHz
        failure_sound   = 2000;  % 2kHz
        pellets_pause   = 1.5;   % pause between pellets if more than one
        pause_duration  = 2;     % pause between trials, after trial end
        buffer_dur      = 1;     % duration of buffer to store current trial angle
        man_pellets     = 0;     % number of pellets delivered manually with "FEED" Button
        num_pellets     = 0;     % number of pellets delivered during the task, excluding man_pellets
    end
    
    methods (Access = private)
        
        function create_lines_in_fig(app)
            app.hit_line        = line(app.KnobAngleAxes,0,0,'Linestyle','--','Color','r');
            app.init_line       = line(app.KnobAngleAxes,0,0,'Linestyle','--','Color','c');
            app.start_line      = line(app.KnobAngleAxes,0,0,'Color',[.5 .5 .5]);
            app.hit_window_line = line(app.KnobAngleAxes,0,0,'Color',[.5 .5 .5]);
            app.angle_line      = line(app.KnobAngleAxes,0,0,'Color','b','LineWidth',2);
        end
        
        function update_GUI_from_params(app)
            
            % fill missing values with defaults from file:
            params = mototrak_knob_params();
            
            % 'rat_id'            , '123',...
            % 'save_dir'          , 'C:\MotoTrak Files\', ...
            % 'hit_window'        , 2,...
            % 'duration'          , 30*60,...
            % 'init_thresh'       , 5,...
            % 'knob_pos'          , 1,...
            % 'hold_time'         , 0,...
            % 'adapt_hold_time'   , false,...
            % 'hold_time_min'     , 0,...
            % 'hold_time_max'     , 1000,...
            % 'hit_thresh'        , 30,...
            % 'adapt_hit_thresh'  , false,...
            % 'hit_thresh_min'     , 30,...
            % 'hit_thresh_max'     , 30,...
            % 'hit_ceil'          , 30,...
            % 'adapt_hit_ceil'    , false,...
            % 'hit_ceil_min'      , 30,...
            % 'hit_ceil_max'      , 30,...
            
            % 'past_10_trials_succ', false(1,10),...
            % 'reward_sound'       , 4000 ,...
            % 'failure_sound'      , 1000,...
            % 'pellets_pause'      , 1.5,...
            % 'buffer_dur'         , 1 ...
            
            app.rat_id.Value            = params.rat_id;
            app.save_dir.Value          = params.save_dir;
            app.hit_window.Value        = params.hit_window;
            app.duration.Value          = params.duration/60;
            app.init_thresh.Value       = params.init_thresh;
            app.knob_pos.Value          = params.knob_pos;
            % hold time
            app.hold_time.Value         = params.hold_time;
            app.adapt_hold_time.Value   = params.adapt_hold_time;
            app.hold_time_min.Value     = params.hold_time_min;
            app.hold_time_max.Value     = params.hold_time_max;
            % hit thresh
            app.hit_thresh.Value        = params.hit_thresh;
            app.adapt_hit_thresh.Value  = params.adapt_hit_thresh;
            app.hit_thresh_min.Value    = params.hit_thresh_min;
            app.hit_thresh_max.Value    = params.hit_thresh_max;
            % hit ceil
            app.hit_ceil.Value          = params.hit_ceil;
            app.adapt_hit_ceil.Value    = params.adapt_hit_ceil;
            app.hit_ceil_min.Value      = params.hit_ceil_min;
            app.hit_ceil_max.Value      = params.hit_ceil_max;
        end
    end
    
    methods (Access = public)
        
        function reset_gui_counters(app)
            app.NumRewardsCounterLabel.Text       = '0';
            app.NumTrialsCounterLabel.Text        = '0';
            app.TimeelapsedCounterLabel.Text      = datestr(0,'HH:MM:SS');
            app.PelletsdeliveredCounterLabel.Text = '0';
            app.num_pellets = 0;
            app.man_pellets = 0;
        end
        
        function update_lines_in_fig(app)
        
            set(app.hit_line,'XData',[0 app.hit_window.Value],'YData',[app.hit_thresh.Value app.hit_thresh.Value]);
            set(app.start_line,'XData',[0 0],'YData',[-10 180]);
            set(app.hit_window_line,'XData',[app.hit_window.Value app.hit_window.Value],'YData',[-10 180]);
            set(app.init_line, 'XData',[-1 0],'YData',[app.init_thresh.Value app.init_thresh.Value]);
            
            %also update axes
            ylim(app.KnobAngleAxes,[-5 app.hit_thresh.Value*1.25]);
            yticks(app.KnobAngleAxes,'auto');
            yticklabels(app.KnobAngleAxes, 'auto');
            xlim(app.KnobAngleAxes,[-1 app.hit_window.Value + 1]);
            xticks(app.KnobAngleAxes, 'auto');
            xticklabels(app.KnobAngleAxes, 'auto');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pos = app.MotoTrakKnobUIFigure.Position;
            %app.MotoTrakKnobUIFigure.Position = [200 300 pos(3:4)];
            unitsEcran = get(0,'Units');
            set(0,'units','pixels');
            %Take screensize
            posScreen = get(0,'screensize');
            %Manage screen position
            pos = [10 10 posScreen(3)/2 posScreen(4)/2];
            update_GUI_from_params(app);
            reset_gui_counters(app);
            create_lines_in_fig(app);
            update_lines_in_fig(app);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MotoTrakKnobUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {521, 521};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {530, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.stop_session = false;
            reset_gui_counters(app);
            set(app.angle_line,'XData',[0 0],'YData',[0 0]);
            update_lines_in_fig(app);
            app.STOPButton.Enable  = true;
            app.STARTButton.Enable = false;
            mototrak_knob_controller(app);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop_session = true;
            app.STARTButton.Enable = true;

        end

        % Button pushed function: FEEDButton
        function FEEDButtonPushed(app, event)
            app.moto.trigger_feeder();
            app.man_pellets = app.man_pellets +1;
            
            %update GUI Label
            app.PelletsdeliveredCounterLabel.Text = sprintf('%d (%.3f g)', ...
                        sum(app.num_pellets)+app.man_pellets, (sum(app.num_pellets)+app.man_pellets)*0.045);
        end

        % Value changed function: adapt_hit_thresh
        function adapt_hit_threshValueChanged(app, event)
            value = app.adapt_hit_thresh.Value;
            if value
                app.hit_thresh_min.Enable = true;
                app.hit_thresh_max.Enable = true;
            else
                app.hit_thresh_min.Enable = false;
                app.hit_thresh_max.Enable = false;
            end
        end

        % Value changed function: ConnectMotoTrakButton
        function ConnectMotoTrakButtonValueChanged(app, event)
            value = app.ConnectMotoTrakButton.Value;
            if value
                 app.moto = Connect_MotoTrak;
                 drawnow;
                 if isempty(app.moto)
                     warning('No mototrak connection!');
                     app.ConnectMotoTrakButton.Value = false;
                 else
                     app.Lamp.Visible = 'on';
                     app.STARTButton.Enable = 'on';
                     app.FEEDButton.Enable = 'on';
                     app.RetractKnobButton.Enable = 'on';
                     % knob init
                     app.moto.knob_toggle(1);                                        %Toggle the knob on.
                     app.moto.clear();                                               %Clear any residual data on serial line -> leads to weird %d format warning
                     app.moto.slope = -0.25;                                         %Set the slope of the calibration.
                     pause(0.1);
                     app.moto.baseline = app.moto.read_Pull();                       %Set the baseline as the current value on the analog line.
                     for i=1:5
                         if isa(app.moto.baseline,'double')
                             break;
                         else
                             disp('problem reading mototrak data. trying again...');
                         end
                         if 1==5
                             disp('giving up...');
                             return;
                         end
                     end
                 end
            else
                if ~isempty(app.moto)
                    fclose(app.moto.serialcon);
                    app.moto = [];
                end
                app.STARTButton.Enable = 'off';
                app.STOPButton.Enable  = 'off';
                app.Lamp.Visible = 'off';
                app.RetractKnobButton.Enable = 'off';
            end
        end

        % Close request function: MotoTrakKnobUIFigure
        function MotoTrakKnobUIFigureCloseRequest(app, event)
            if ~isempty(app.moto)
                fclose(app.moto.serialcon);
                app.moto = [];
            end
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            save_dir = uigetdir(app.save_dir.Value, 'Select folder in which to save the results');
            if save_dir
                app.save_dir.Value=save_dir;
            end
        end

        % Value changed function: RetractKnobButton
        function RetractKnobButtonValueChanged(app, event)
            value = app.RetractKnobButton.Value;
            if value
                % move to 4cm outside
                app.moto.autopositioner(-100*(4-4.75));
            else
                % move to specified position
                app.moto.autopositioner(-100*(app.knob_pos.Value-4.75));
            end
        end

        % Value changed function: hit_thresh
        function hit_threshValueChanged(app, event)
            update_lines_in_fig(app);
        end

        % Value changed function: rat_id
        function rat_idValueChanged(app, event)
            value = app.rat_id.Value;

            [last_hit_thresh, app.Median_peak] = read_from_global_stats(value,app.save_dir.Value);
            app.MedianPeakValueLabel.Text = num2str(app.Median_peak);
            
            if ~isempty(last_hit_thresh)
                answer = questdlg(sprintf('A previous hit threshold of %.0f deg exists for this rat %s, do you want to use it ?',last_hit_thresh,value), 'For you information', 'Yes','No','Yes');

                if strcmp(answer, 'Yes')
                    app.hit_thresh.Value = last_hit_thresh;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotoTrakKnobUIFigure = uifigure('Visible', 'off');
            app.MotoTrakKnobUIFigure.Name = 'MotoTrak Knob Controller';
            app.MotoTrakKnobUIFigure.CloseRequestFcn = createCallbackFcn(app, @MotoTrakKnobUIFigureCloseRequest, true);
            app.MotoTrakKnobUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MotoTrakKnobUIFigure);
            app.MainPanel = uipanel(app.GridLayout);
            app.MotoTrakKnobTaskLabel = uilabel(app.MainPanel);
            app.MotoTrakKnobTaskLabel.Text = 'MotoTrak Knob Task';
            app.RatIDEditFieldLabel = uilabel(app.MainPanel);
            app.RatIDEditFieldLabel.Text = 'Rat ID:';
            app.rat_id = uieditfield(app.MainPanel, 'text');
            app.rat_id.ValueChangedFcn = createCallbackFcn(app, @rat_idValueChanged, true);
            app.SavelocationparentfolderEditFieldLabel = uilabel(app.MainPanel);
            app.SavelocationparentfolderEditFieldLabel.Text = 'Save location (parent folder):';
            app.save_dir = uieditfield(app.MainPanel, 'text');
            app.BrowseButton = uibutton(app.MainPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ...';
            app.ParametersPanel = uipanel(app.MainPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.DurationminEditFieldLabel = uilabel(app.ParametersPanel);
            app.DurationminEditFieldLabel.Text = 'Duration (min):';
            app.duration = uieditfield(app.ParametersPanel, 'numeric');
            app.HitthreshdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitthreshdegEditFieldLabel.Text = 'Hit thresh (deg):';
            app.hit_thresh = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh.ValueChangedFcn = createCallbackFcn(app, @hit_threshValueChanged, true);
            app.adaptiveLabel = uilabel(app.ParametersPanel);
            app.adaptiveLabel.Text = 'adaptive';
            app.adapt_hit_thresh = uicheckbox(app.ParametersPanel);
            app.adapt_hit_thresh.ValueChangedFcn = createCallbackFcn(app, @adapt_hit_threshValueChanged, true);
            app.adapt_hit_thresh.Text = '';
            app.minLabel = uilabel(app.ParametersPanel);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.ParametersPanel);
            app.maxLabel.Text = 'max';
            app.hit_thresh_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh_min.Enable = 'off';
            app.hit_thresh_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh_max.Enable = 'off';
            app.adapt_hit_ceil = uicheckbox(app.ParametersPanel);
            app.adapt_hit_ceil.Enable = 'off';
            app.adapt_hit_ceil.Text = '';
            app.hit_ceil_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil_min.Editable = 'off';
            app.hit_ceil_min.Enable = 'off';
            app.hit_ceil_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil_max.Editable = 'off';
            app.hit_ceil_max.Enable = 'off';
            app.HitceilingdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitceilingdegEditFieldLabel.Enable = 'off';
            app.HitceilingdegEditFieldLabel.Text = 'Hit ceiling (deg):';
            app.hit_ceil = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil.Enable = 'off';
            app.HitwindowsEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitwindowsEditFieldLabel.Text = 'Hit window (s):';
            app.hit_window = uieditfield(app.ParametersPanel, 'numeric');
            app.adapt_hold_time = uicheckbox(app.ParametersPanel);
            app.adapt_hold_time.Enable = 'off';
            app.adapt_hold_time.Text = '';
            app.hold_time_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time_min.Editable = 'off';
            app.hold_time_min.Enable = 'off';
            app.hold_time_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time_max.Editable = 'off';
            app.hold_time_max.Enable = 'off';
            app.HoldtimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.HoldtimesEditFieldLabel.Enable = 'off';
            app.HoldtimesEditFieldLabel.Text = 'Hold time (s):';
            app.hold_time = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time.Editable = 'off';
            app.hold_time.Enable = 'off';
            app.KnobposcmEditFieldLabel = uilabel(app.ParametersPanel);
            app.KnobposcmEditFieldLabel.Text = 'Knob pos (cm):';
            app.knob_pos = uieditfield(app.ParametersPanel, 'numeric');
            app.InitthreshdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.InitthreshdegEditFieldLabel.Text = 'Init thresh (deg):';
            app.init_thresh = uieditfield(app.ParametersPanel, 'numeric');
            app.STARTButton = uibutton(app.MainPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.MainPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.FEEDButton = uibutton(app.MainPanel, 'push');
            app.FEEDButton.ButtonPushedFcn = createCallbackFcn(app, @FEEDButtonPushed, true);
            app.FEEDButton.Enable = 'off';
            app.FEEDButton.Text = 'FEED';
            app.ConnectMotoTrakButton = uibutton(app.MainPanel, 'state');
            app.ConnectMotoTrakButton.ValueChangedFcn = createCallbackFcn(app, @ConnectMotoTrakButtonValueChanged, true);
            app.ConnectMotoTrakButton.Text = {'Connect'; 'MotoTrak'};
            app.Label = uilabel(app.MainPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.Lamp = uilamp(app.MainPanel);
            app.Lamp.Visible = 'off';
            app.RetractKnobButton = uibutton(app.MainPanel, 'state');
            app.RetractKnobButton.ValueChangedFcn = createCallbackFcn(app, @RetractKnobButtonValueChanged, true);
            app.RetractKnobButton.Enable = 'off';
            app.RetractKnobButton.Text = {'Retract'; 'Knob'};
            app.RightPanel = uipanel(app.GridLayout);
            app.KnobAngleAxes = uiaxes(app.RightPanel);
            title(app.KnobAngleAxes, 'Knob Rotation Angle')
            xlabel(app.KnobAngleAxes, 'Time (s)')
            ylabel(app.KnobAngleAxes, 'Angle (deg)')
            app.KnobAngleAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.KnobAngleAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.KnobAngleAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PelletsdeliveredLabel = uilabel(app.RightPanel);
            app.PelletsdeliveredLabel.Text = 'Pellets delivered:';
            app.PelletsdeliveredCounterLabel = uilabel(app.RightPanel);
            app.PelletsdeliveredCounterLabel.Text = '0 (0.000 g)';
            app.TimeelapsedLabel = uilabel(app.RightPanel);
            app.TimeelapsedLabel.Text = 'Time elapsed:';
            app.TimeelapsedCounterLabel = uilabel(app.RightPanel);
            app.TimeelapsedCounterLabel.Text = '00:00:00';
            app.NumTrialsLabel = uilabel(app.RightPanel);
            app.NumTrialsLabel.Text = 'Num Trials: ';
            app.NumRewardsLabel = uilabel(app.RightPanel);
            app.NumRewardsLabel.Text = 'Num Rewards:';
            app.NumTrialsCounterLabel = uilabel(app.RightPanel);
            app.NumTrialsCounterLabel.Text = '0';
            app.NumRewardsCounterLabel = uilabel(app.RightPanel);
            app.NumRewardsCounterLabel.Text = '0';
            app.MedianPeakLabel = uilabel(app.RightPanel);
            app.MedianPeakLabel.Text = 'Median Peak:';
            app.MedianPeakValueLabel = uilabel(app.RightPanel);
            app.MedianPeakValueLabel.Text = '0';
            % Show the figure after all components are created
            app.MotoTrakKnobUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motoknob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotoTrakKnobUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotoTrakKnobUIFigure)
        end
    end
end