classdef BaselineAdjustment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        BaselineParameterCorrectionPanel  matlab.ui.container.Panel
        SensorValueaverageEditFieldLabel  matlab.ui.control.Label
        SensorValueaverageEditField  matlab.ui.control.NumericEditField
        OffsetEditFieldLabel         matlab.ui.control.Label
        OffsetEditField              matlab.ui.control.NumericEditField
        CorrectOffsetButton          matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        SensorPanel                  matlab.ui.container.Panel
        OnButton                     matlab.ui.control.Button
        OffButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        motoTaskMain % Main app
        loopOn=0; % Loop on
        newBaseline; % Corrected baseline
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, motoTask)
            app.motoTaskMain=motoTask;
            app.SensorValueaverageEditField.Value=app.motoTaskMain.moto.read_Pull();
            app.OffsetEditField.Value=app.motoTaskMain.moto.read_Pull()-app.motoTaskMain.moto.baseline;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             % Delete the dialog box  
             app.loopOn=false;
             app.motoTaskMain.AdjustLeverBaselineButton.Enable="off";
             
             delete(app)     
        end

        % Callback function
        function SensorButtonGroupSelectionChanged(app, event)
                     
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            n=30;
            window=10; % range to show data in second
            streamValSensor=ones(1,n).*app.motoTaskMain.moto.read_Pull();
            step=(window/n);
            temps=-(n-1):1:0;temps=temps.*step;
            val1=app.motoTaskMain.moto.read_Pull()-(0.2*app.motoTaskMain.moto.read_Pull());
            val2=app.motoTaskMain.moto.read_Pull()+(0.2*app.motoTaskMain.moto.read_Pull());
            limit_Y=[min([val1 val2]) max([val1 val2])];
            plot(app.UIAxes,temps,streamValSensor);ylim(app.UIAxes,limit_Y);
            app.loopOn=1;
            
            while app.loopOn
                streamValSensor(end+1)=app.motoTaskMain.moto.read_Pull();
                streamValSensor(1)=[];
                plot(app.UIAxes,temps,streamValSensor);
                app.SensorValueaverageEditField.Value=mean(streamValSensor);
                pause(step)
                drawnow
            end
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            app.loopOn=0;
           
        end

        % Callback function
        function CurrentCorrectionEditFieldValueChanged(app, event)
            value = app.CurrentCorrectionEditField.Value;
            app.motoTaskMain.moto.baseline=app.motoTaskMain.moto.baseline-value;
        end

        % Callback function
        function ButtonPushed(app, event)
            app.CurrentCorrectionEditField.Value=app.CurrentCorrectionEditField.Value*1.5;
        end

        % Button pushed function: CorrectOffsetButton
        function CorrectOffsetButtonPushed(app, event)
             % Delete the dialog box  
             app.loopOn=false;
             app.newBaseline=app.SensorValueaverageEditField.Value;
             updateLeverCalibration(app.motoTaskMain,app.newBaseline)
             app.motoTaskMain.AdjustLeverBaselineButton.Enable='On';
             delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Baseline Adjustment')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Sensor Value')
            app.BaselineParameterCorrectionPanel = uipanel(app.UIFigure);
            app.BaselineParameterCorrectionPanel.Title = 'Baseline Parameter Correction';
            app.SensorValueaverageEditFieldLabel = uilabel(app.BaselineParameterCorrectionPanel);
            app.SensorValueaverageEditFieldLabel.Text = 'Sensor Value average';
            app.SensorValueaverageEditField = uieditfield(app.BaselineParameterCorrectionPanel, 'numeric');
            app.OffsetEditFieldLabel = uilabel(app.BaselineParameterCorrectionPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.BaselineParameterCorrectionPanel, 'numeric');
            app.CorrectOffsetButton = uibutton(app.UIFigure, 'push');
            app.CorrectOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectOffsetButtonPushed, true);
            app.CorrectOffsetButton.Text = {'Correct'; 'Offset'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SensorPanel = uipanel(app.UIFigure);
            app.SensorPanel.Title = 'Sensor';
            app.OnButton = uibutton(app.SensorPanel, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.SensorPanel, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaselineAdjustment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end