classdef motoTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotoTrakKnobUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        HistoricalHTLabel             matlab.ui.control.Label
        HistoricalHTValueLabel        matlab.ui.control.Label
        NumStimulationsCounterLabel   matlab.ui.control.Label
        NumStimulationsLabel          matlab.ui.control.Label
        MedianPeakValueLabel          matlab.ui.control.Label
        MedianPeakLabel               matlab.ui.control.Label
        NumRewardsCounterLabel        matlab.ui.control.Label
        NumTrialsCounterLabel         matlab.ui.control.Label
        NumRewardsLabel               matlab.ui.control.Label
        NumTrialsLabel                matlab.ui.control.Label
        TimeelapsedCounterLabel       matlab.ui.control.Label
        TimeelapsedLabel              matlab.ui.control.Label
        PelletsdeliveredCounterLabel  matlab.ui.control.Label
        PelletsdeliveredLabel         matlab.ui.control.Label
        moduleValueAxes               matlab.ui.control.UIAxes
        MainPanel                     matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        StimulationPanel              matlab.ui.container.Panel
        StimGroup                     matlab.ui.container.ButtonGroup
        ManualStimButton              matlab.ui.control.RadioButton
        histoHT_dir                   matlab.ui.control.EditField
        HistoricalHitThresholdLabel   matlab.ui.control.Label
        Browse_histoHT                matlab.ui.control.Button
        histo_tolerance               matlab.ui.control.NumericEditField
        tolerancedegLabel             matlab.ui.control.Label
        StimeRate                     matlab.ui.control.NumericEditField
        StimeRateLabel                matlab.ui.control.Label
        RandStim                      matlab.ui.control.RadioButton
        StimDuration                  matlab.ui.control.NumericEditField
        StimDurationmsEditFieldLabel  matlab.ui.control.Label
        ManualStim                    matlab.ui.control.Button
        RewardStim                    matlab.ui.control.RadioButton
        OFF                           matlab.ui.control.RadioButton
        PAUSECheckBox                 matlab.ui.control.CheckBox
        ChangeButton                  matlab.ui.control.Button
        fileCalibName                 matlab.ui.control.EditField
        CalibrationfilelocationLabel  matlab.ui.control.Label
        RetractSensoratPosButton      matlab.ui.control.StateButton
        ConnectMotoTrakButton         matlab.ui.control.StateButton
        FEEDButton                    matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        AdjustLeverBaselineButton     matlab.ui.control.Button
        failure_tolerance             matlab.ui.control.NumericEditField
        FailuretolerancedegLabel      matlab.ui.control.Label
        hold_time_max                 matlab.ui.control.NumericEditField
        MaxTrialNum                   matlab.ui.control.NumericEditField
        MaxTrialsnumLabel             matlab.ui.control.Label
        init_thresh                   matlab.ui.control.NumericEditField
        InitthreshdegEditFieldLabel   matlab.ui.control.Label
        knob_pos                      matlab.ui.control.NumericEditField
        SensorposcmLabel              matlab.ui.control.Label
        hold_time                     matlab.ui.control.NumericEditField
        HoldtimesEditFieldLabel       matlab.ui.control.Label
        hold_time_min                 matlab.ui.control.NumericEditField
        adapt_hold_time               matlab.ui.control.CheckBox
        hit_window                    matlab.ui.control.NumericEditField
        HitwindowsEditFieldLabel      matlab.ui.control.Label
        hit_ceil                      matlab.ui.control.NumericEditField
        HitceilingdegEditFieldLabel   matlab.ui.control.Label
        hit_ceil_max                  matlab.ui.control.NumericEditField
        hit_ceil_min                  matlab.ui.control.NumericEditField
        adapt_hit_ceil                matlab.ui.control.CheckBox
        hit_thresh_max                matlab.ui.control.NumericEditField
        hit_thresh_min                matlab.ui.control.NumericEditField
        maxLabel                      matlab.ui.control.Label
        minLabel                      matlab.ui.control.Label
        adapt_hit_thresh              matlab.ui.control.CheckBox
        adaptiveLabel                 matlab.ui.control.Label
        hit_thresh                    matlab.ui.control.NumericEditField
        HitthreshdegEditFieldLabel    matlab.ui.control.Label
        TaskDuration                  matlab.ui.control.NumericEditField
        MaxDurationminLabel           matlab.ui.control.Label
        SaveBrowseButton              matlab.ui.control.Button
        save_dir                      matlab.ui.control.EditField
        SavelocationparentfolderEditFieldLabel  matlab.ui.control.Label
        rat_id                        matlab.ui.control.EditField
        RatIDEditFieldLabel           matlab.ui.control.Label
        MotoTrakLabel                 matlab.ui.control.Label
    end


    properties (Access = private)
        calibApp                 % l'object uifigure calibration créé
        BaselineApp % Baseline sensor adjustment
    end

    properties (Access = public)
        moto = []       ;        % mototrak object
        hit_line        ;        %= line(app.moduleValueAxes,0,0,'--r');
        init_line       ;        %= line(app.moduleValueAxes,0,0,'--b');
        start_line      ;        %= line(app.moduleValueAxes,0,0,'Color',[.5 .5 .5]);
        hit_window_line ;        %= line(app.moduleValueAxes,0,0,'Color',[.5 .5 .5]);
        angle_line      ;        %= line(app.moduleValueAxes,0,0,'Color','b','LineWidth',2);
        stop_session    ;        % flag to stop the session when STOP button is clicked
        pause_session   ;        % flag to pause the session when PAUSE button is clicked
        Median_peak     = 0;     % ongoing median of angle peak for last 10 trials
        reward_sound    = 8000;  % 8kHz
        failure_sound   = 2000;  % 2kHz
        pellets_pause   = 1.5;   % pause between pellets if more than one
        pause_duration  = 2;     % pause between trials, after trial end
        buffer_dur      = 1;     % duration of buffer to store current trial angle
        man_pellets     = 0;     % number of pellets delivered manually with "FEED" Button
        num_pellets     = 0;     % number of pellets delivered during the task, excluding man_pellets
        num_stimulations = 0;
        module   = 'unknow';     % type of module connect : knob or lever
        leverCalibData=[];       % [weight(g) SensorinputValue]
        calib_Coeff_lever=[0 0]; % coefficient de la calibration pour le levier []
        historical_hit_thresh_max = 40;
        historical_hit_thresh_max_vec=[];
        StimCond=0;
        StimOrder = false;
    end

    methods (Access = private)

        function create_lines_in_fig(app)
            app.hit_line        = line(app.moduleValueAxes,0,0,'Linestyle','--','Color','r');
            app.init_line       = line(app.moduleValueAxes,0,0,'Linestyle','--','Color','c');
            app.start_line      = line(app.moduleValueAxes,0,0,'Color',[.5 .5 .5]);
            app.hit_window_line = line(app.moduleValueAxes,0,0,'Color',[.5 .5 .5]);
            app.angle_line      = line(app.moduleValueAxes,0,0,'Color','b','LineWidth',2);
        end

        function update_GUI_from_params(app)

            % fill missing values with defaults from file:
            params = mototrak_knob_params();

            % 'rat_id'            , '123',...
            % 'save_dir'          , 'C:\MotoTrak Files\', ...
            % 'load_dir'          , 40,...
            % 'fileCalibName'     , 'C:\MotoTrak Files\Calibration1', ...
            % 'hit_window'        , 2,...
            % 'duration'          , 30*60,...
            % 'MaxTrialNum'       , 500,...
            % 'init_thresh'       , 5,...
            % 'knob_pos'          , 1,...
            % 'histo_tolerance'   , 20,...
            % 'failure_tolerance' , 0,...
            % 'hold_time'         , 0,...
            % 'adapt_hold_time'   , false,...
            % 'hold_time_min'     , 0,...
            % 'hold_time_max'     , 1000,...
            % 'hit_thresh'        , 30,...
            % 'adapt_hit_thresh'  , false,...
            % 'hit_thresh_min'     , 30,...
            % 'hit_thresh_max'     , 30,...
            % 'hit_ceil'          , 30,...
            % 'adapt_hit_ceil'    , false,...
            % 'hit_ceil_min'      , 30,...
            % 'hit_ceil_max'      , 30,...

            % 'past_10_trials_succ', false(1,10),...
            % 'reward_sound'       , 4000 ,...
            % 'failure_sound'      , 1000,...
            % 'pellets_pause'      , 1.5,...
            % 'buffer_dur'         , 1 ...

            %
            app.rat_id.Value            = params.rat_id;
            app.save_dir.Value          = params.save_dir;
            app.histoHT_dir.Value       = params.histoHT_dir;
            app.fileCalibName.Value     = strcat(params.save_dir,'Calibration2.mat');
            app.hit_window.Value        = params.hit_window;
            app.TaskDuration.Value      = params.duration/60;
            app.MaxTrialNum.Value       = params.MaxTrialNum;
            app.init_thresh.Value       = params.init_thresh;
            app.knob_pos.Value          = params.knob_pos;
            %

            app.histo_tolerance.Value = params.histo_tolerance;
            app.failure_tolerance.Value = params.failure_tolerance;
            % hold time
            app.hold_time.Value         = params.hold_time;
            app.adapt_hold_time.Value   = params.adapt_hold_time;
            app.hold_time_min.Value     = params.hold_time_min;
            app.hold_time_max.Value     = params.hold_time_max;
            % hit thresh
            app.hit_thresh.Value        = params.hit_thresh;
            app.adapt_hit_thresh.Value  = params.adapt_hit_thresh;
            app.hit_thresh_min.Value    = params.hit_thresh_min;
            app.hit_thresh_max.Value    = params.hit_thresh_max;
            % hit ceil
            app.hit_ceil.Value          = params.hit_ceil;
            app.adapt_hit_ceil.Value    = params.adapt_hit_ceil;
            app.hit_ceil_min.Value      = params.hit_ceil_min;
            app.hit_ceil_max.Value      = params.hit_ceil_max;
        end

        function results = func6(app)

        end
    end

    methods (Access = public)

        function reset_gui_counters(app)
            app.NumRewardsCounterLabel.Text       = '0';
            app.NumStimulationsCounterLabel.Text  = '0';
            app.NumTrialsCounterLabel.Text        = '0';
            app.TimeelapsedCounterLabel.Text      = datestr(0,'HH:MM:SS');
            app.PelletsdeliveredCounterLabel.Text = '0';
            app.num_pellets = 0;
            app.man_pellets = 0;
            app.num_stimulations = 0;

        end

        function update_lines_in_fig(app)

            set(app.hit_line,'XData',[0 app.hit_window.Value],'YData',[app.hit_thresh.Value app.hit_thresh.Value]);
            set(app.start_line,'XData',[0 0],'YData',[-10 180]);
            set(app.hit_window_line,'XData',[app.hit_window.Value app.hit_window.Value],'YData',[-10 180]);
            set(app.init_line, 'XData',[-1 0],'YData',[app.init_thresh.Value app.init_thresh.Value]);

            %also update axes
            ylim(app.moduleValueAxes,[-5 app.hit_thresh.Value*1.25]);
            yticks(app.moduleValueAxes,'auto');
            yticklabels(app.moduleValueAxes, 'auto');
            xlim(app.moduleValueAxes,[-1 app.hit_window.Value + 1]);
            xticks(app.moduleValueAxes, 'auto');
            xticklabels(app.moduleValueAxes, 'auto');
        end


        function  gui_display(app)
            type=app.module;

            if strcmp(type,'lever')
                app.MotoTrakLabel.Text="Moto Trak Lever";
                app.RetractSensoratPosButton.Text=['Retract',newline,'Lever'];
                app.SensorposcmLabel.Text="Lever position (cm) :";
                app.SensorposcmLabel.Position(3)=app.SensorposcmLabel.Position(3)+20;
                app.moduleValueAxes.Title.String='Lever Force';
                app.moduleValueAxes.YLabel.String='Force (g)';
                app.InitthreshdegEditFieldLabel.Text= 'Init thresh (g):';
                app.HitthreshdegEditFieldLabel.Text='Hit thresh (g):';
                app.AdjustLeverBaselineButton.Visible="on";

            elseif strcmp(type,'knob')
                app.MotoTrakLabel.Text="Moto Trak Knob";
                app.RetractSensoratPosButton.Text=['Retract',newline,'Knob'];
                app.SensorposcmLabel.Text="Knob position (cm) :";
                app.SensorposcmLabel.Position(3)=app.SensorposcmLabel.Position(3)+20;
                app.moduleValueAxes.Title.String='Knob Angle';
            end
        end


        function updateLeverCalibration(app,newBaseline)
            app.moto.baseline=newBaseline;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pos = app.MotoTrakKnobUIFigure.Position;
            %app.MotoTrakKnobUIFigure.Position = [200 300 pos(3:4)];
            unitsEcran = get(0,'Units');
            set(0,'units','pixels');
            %Take screensize
            posScreen = get(0,'screensize');
            %Manage screen position
            pos = [10 10 posScreen(3)/2 posScreen(4)/2];
            update_GUI_from_params(app);
            reset_gui_counters(app);
            create_lines_in_fig(app);
            update_lines_in_fig(app);

        end

        % Size changed function: MotoTrakKnobUIFigure
        function updateAppLayout(app, event)
            position = app.MotoTrakKnobUIFigure.Position;

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.stop_session = false;
            reset_gui_counters(app);
            set(app.angle_line,'XData',[0 0],'YData',[0 0]);
            update_lines_in_fig(app);
            app.pause_session = false;
            app.STOPButton.Enable  = true;
            app.STARTButton.Enable = false;
            app.PAUSECheckBox.Enable = true;

            if strcmpi(app.module,'lever')
                mototrak_controller(app)
            elseif strcmpi(app.module,'knob')
                mototrak_controller(app);
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop_session = true;
            app.STARTButton.Enable = true;
            app.PAUSECheckBox.Enable = false;
        end

        % Button pushed function: FEEDButton
        function FEEDButtonPushed(app, event)
            app.moto.trigger_feeder();
            app.man_pellets = app.man_pellets +1;

            %update GUI Label
            app.PelletsdeliveredCounterLabel.Text = sprintf('%d (%.3f g)', ...
                sum(app.num_pellets)+app.man_pellets, (sum(app.num_pellets)+app.man_pellets)*0.045);
        end

        % Value changed function: adapt_hit_thresh
        function adapt_hit_threshValueChanged(app, event)
            value = app.adapt_hit_thresh.Value;
            if value
                app.hit_thresh_min.Enable = true;
                app.hit_thresh_max.Enable = true;
            else
                app.hit_thresh_min.Enable = false;
                app.hit_thresh_max.Enable = false;
            end
        end

        % Value changed function: ConnectMotoTrakButton
        function ConnectMotoTrakButtonValueChanged(app, event)
            value = app.ConnectMotoTrakButton.Value;
            if value
                app.moto = Connect_MotoTrak;
                drawnow;
                if isempty(app.moto)
                    warning('No mototrak connection!');
                    app.ConnectMotoTrakButton.Value = false;
                else % Connected module

                    selection = uiconfirm(app.MotoTrakKnobUIFigure,'Which Device Connected','Device Selection','Options',{'Lever','Knob','Cancel'}) ;

                    if strcmp(selection,'Lever')
                        app.module='lever';% connected module is lever
                        disp('Lever detected')
                        u=1;
                        while u<4
                            calibfilename=app.fileCalibName.Value;% calibration name file for lever
                            calibfilename_end_pos=find(calibfilename=='\');calibfilename_end_pos=calibfilename_end_pos(end)+1;...
                                calibfilename_end=calibfilename(calibfilename_end_pos:end);

                            if isfile(calibfilename)
                                app.calib_Coeff_lever = load(calibfilename);
                                app.calib_Coeff_lever=  app.calib_Coeff_lever.coefficients;% p(1)=pente; p(2) ordonnée à l'origine
                                u=10;
                            else
                                h=errordlg({'Can''t Find Calibration File';'Please Select Calibration File'} );h.Position=[300 300 200 70];
                                pause(5)
                                close(h);
                                ChangeButtonPushed(app, event);
                                u=u+1;
                            end
                        end
                        if u==4
                            errordlg({'Lever Task Can''t be done Without Calibration'} );
                        end
                        gui_display(app);

                    elseif strcmp(selection,'Knob')
                        app.module='knob'; %  connected module is knob
                        disp('knob detected')
                        gui_display(app);
                    end

                    app.Lamp.Visible = 'on';
                    app.STARTButton.Enable = 'on';
                    app.FEEDButton.Enable = 'on';
                    app.RetractSensoratPosButton.Enable = 'on';
                    app.ManualStim.Enable = "on";
                    % module init
                    %Clear any residual data on serial line -> leads to weird %d format warning
                    if strcmp(app.module,'knob')
                        app.moto.knob_toggle(1);                                        %Toggle the knob on.
                        app.moto.clear();
                        app.moto.slope = -0.25;                                         %Set the slope of the calibration.
                        pause(0.1);
                        app.moto.baseline = app.moto.read_Pull();

                        %Set the baseline as the current value on the analog line.
                        for i=1:5
                            if isa(app.moto.baseline,'double')
                                break;
                            else
                                disp('problem reading mototrak data. trying again...');
                            end
                            if i==5
                                disp('giving up...');
                                return;
                            end
                        end
                    elseif strcmp(app.module,'lever')
                        app.moto.slope = app.calib_Coeff_lever(1);                                    %Set the slope of the calibration.
                        pause(0.1);
                        app.moto.baseline = app.calib_Coeff_lever(2);
                    end


                end
            else
                if ~isempty(app.moto)
                    fclose(app.moto.serialcon);
                    app.moto = [];
                end
                app.STARTButton.Enable = 'off';
                app.STOPButton.Enable  = 'off';
                app.Lamp.Visible = 'off';
                app.RetractSensoratPosButton.Enable = 'off';
            end
        end

        % Close request function: MotoTrakKnobUIFigure
        function MotoTrakKnobUIFigureCloseRequest(app, event)
            if ~isempty(app.moto)
                fclose(app.moto.serialcon);
                app.moto = [];
            end
            delete(app)
        end

        % Button pushed function: SaveBrowseButton
        function SaveBrowseButtonPushed(app, event)
            save_dir = uigetdir(app.save_dir.Value, 'Select folder in which to save the results');
            if save_dir
                app.save_dir.Value=save_dir;
            end
        end

        % Value changed function: RetractSensoratPosButton
        function RetractSensoratPosButtonValueChanged(app, event)
            % value = app.RetractSensoratPosButton.Value;
            % move to specified position
            app.moto.autopositioner(-100*(app.knob_pos.Value-4.75));

        end

        % Value changed function: hit_thresh
        function hit_threshValueChanged(app, event)
            update_lines_in_fig(app);
        end

        % Value changed function: rat_id
        function rat_idValueChanged(app, event)
            value = app.rat_id.Value;


            [last_hit_thresh, last_Median_peak,historical_HT_max] = read_from_global_stats(value,app.save_dir.Value,app.module);

            app.MedianPeakValueLabel.Text = num2str(last_Median_peak);
            app.HistoricalHTValueLabel.Text = num2str(historical_HT_max);

            if ~isempty(last_hit_thresh)
                answer = questdlg(sprintf('A previous hit threshold of %.0f deg exists for this rat %s, do you want to use it ?',last_hit_thresh,value), 'For you information', 'Yes','No','Yes');

                if strcmp(answer, 'Yes')
                    app.hit_thresh.Value = last_hit_thresh;
                end
            end
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
            LeverCalibration(app);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.fileCalibName.Value=strcat(path,file);

        end

        % Button pushed function: AdjustLeverBaselineButton
        function AdjustLeverBaselineButtonPushed(app, event)
            % Disable adjust button while dialog is open
            app.AdjustLeverBaselineButton.Enable='off';

            % Call Adjustment figure with input values
            app.BaselineApp= BaselineAdjustment(app);

        end

        % Callback function
        function PAUSEButtonPushed(app, event)
            app.PAUSEButton.Enable = true;
        end

        % Value changed function: PAUSECheckBox
        function PAUSECheckBoxValueChanged(app, event)
            value = app.PAUSECheckBox.Value;
            if value

                app.pause_session = true;
            else

                app.pause_session = false;
            end
        end

        % Callback function
        function ManualStimButtonPushed(app, event)
            app.moto.stim();
        end

        % Callback function
        function StimDurationValueChanged(app, event)
            value = app.StimDuration.Value;
            app.moto.set_stim_dur(value);
        end

        % Callback function
        function BrowseButton_loadPushed(app, event)

            % Open a file dialog to select the file
            [filename, path] = uigetfile('*.mat', 'Select History File');

            if isequal(filename, 0) || isequal(path, 0)
                % User canceled the file selection
                disp('File selection canceled.');
                return;
            end

            % Construct the full file path
            fullpath = fullfile(path, filename);

            % Load the data from the file
            try
                data = load(fullpath, 'historical_hit_thresh_max_vec');
                app.historical_hit_thresh_max_vec = data.historical_hit_thresh_max_vec;
                app.historical_hit_thresh_max=app.historical_hit_thresh_max_vec(end);
                % Process the loaded data as needed
                disp('Historical hit threshold loaded successfully.');

            catch
                disp('Error loading the file.');
            end

        end

        % Callback function
        function histoHT_ValueChanged(app, event)

            app.historical_hit_thresh_max = app.histoHT_dir.Value;

        end

        % Button pushed function: ManualStim
        function ManualStimButtonPushed2(app, event)
            app.StimOrder = true;
        end

        % Selection changed function: StimGroup
        function StimGroupSelectionChanged(app, event)
            selectedButton = app.StimGroup.SelectedObject;
            % Check which button was selected and execute the corresponding code

            app.StimDuration.Enable  = 'off';
            app.histoHT_dir.Enable = 'off';
            app.Browse_histoHT.Enable = 'off';
            app.histo_tolerance.Enable = 'off';
            app.StimeRate.Enable = 'off';
            app.ManualStim.Enable = 'off';

            app.adapt_hit_thresh.Enable = 'on';
            app.hit_thresh.Enable = 'on';

            if app.OFF.Value
                app.StimCond=0;
                disp("Stimulation disabled.")

            elseif app.RewardStim.Value
                app.StimDuration.Enable  = 'on';
                app.histoHT_dir.Enable = 'on';
                app.Browse_histoHT.Enable = 'on';
                app.histo_tolerance.Enable = 'on';

                app.StimCond=1;
                disp("Reward stimulation enabled.")

                app.adapt_hit_thresh.Enable = 'off';
                app.hit_thresh.Enable = 'off';

            elseif app.RandStim.Value

                app.StimeRate.Enable = 'on';
                app.StimCond=2;
                disp("Random stimulation enabled.")

            elseif app.ManualStimButton.Value
                app.ManualStim.Enable = 'on';
                app.StimCond=3;
                disp("Manual stimulation enabled.")
            end

        end

        % Button pushed function: Browse_histoHT
        function Browse_histoHTButtonPushed(app, event)

            histoHT_filename = uigetdir(app.histoHT_dir.Value, 'Select the historical hit threshold file');
            if app.histoHT_dir
                histoHT_file = load(histoHT_filename);

                if any(strcmp('Historical_HT_max', global_stats.Properties.VariableNames))
                    app.histoHT_dir.Value = global_stats.Historical_HT_max(end);
                end
                %             else
                %                 disp(['Historical Hit Threshold was not found for the rat ']);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotoTrakKnobUIFigure = uifigure('Visible', 'off');
            app.MotoTrakKnobUIFigure.Name = 'MotoTrak Knob Controller';
            app.MotoTrakKnobUIFigure.CloseRequestFcn = createCallbackFcn(app, @MotoTrakKnobUIFigureCloseRequest, true);
            app.MotoTrakKnobUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MotoTrakKnobUIFigure);
            app.MainPanel = uipanel(app.GridLayout);
            app.MotoTrakLabel = uilabel(app.MainPanel);
            app.MotoTrakLabel.Text = 'MotoTrak';
            app.RatIDEditFieldLabel = uilabel(app.MainPanel);
            app.RatIDEditFieldLabel.Text = 'Rat ID:';
            app.rat_id = uieditfield(app.MainPanel, 'text');
            app.rat_id.ValueChangedFcn = createCallbackFcn(app, @rat_idValueChanged, true);
            app.SavelocationparentfolderEditFieldLabel = uilabel(app.MainPanel);
            app.SavelocationparentfolderEditFieldLabel.Text = 'Save location (parent folder):';
            app.save_dir = uieditfield(app.MainPanel, 'text');
            app.SaveBrowseButton = uibutton(app.MainPanel, 'push');
            app.SaveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBrowseButtonPushed, true);
            app.SaveBrowseButton.Text = 'Browse';
            app.ParametersPanel = uipanel(app.MainPanel);
            app.ParametersPanel.Title = 'Parameters:';
            app.MaxDurationminLabel = uilabel(app.ParametersPanel);
            app.MaxDurationminLabel.Text = 'Max Duration (min):';
            app.TaskDuration = uieditfield(app.ParametersPanel, 'numeric');
            app.TaskDuration.Value = 30;
            app.HitthreshdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitthreshdegEditFieldLabel.Text = 'Hit thresh (deg):';
            app.hit_thresh = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh.ValueChangedFcn = createCallbackFcn(app, @hit_threshValueChanged, true);
            app.adaptiveLabel = uilabel(app.ParametersPanel);
            app.adaptiveLabel.Text = 'adaptive';
            app.adapt_hit_thresh = uicheckbox(app.ParametersPanel);
            app.adapt_hit_thresh.ValueChangedFcn = createCallbackFcn(app, @adapt_hit_threshValueChanged, true);
            app.adapt_hit_thresh.Text = '';
            app.minLabel = uilabel(app.ParametersPanel);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.ParametersPanel);
            app.maxLabel.Text = 'max';
            app.hit_thresh_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh_min.Enable = 'off';
            app.hit_thresh_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_thresh_max.Enable = 'off';
            app.adapt_hit_ceil = uicheckbox(app.ParametersPanel);
            app.adapt_hit_ceil.Enable = 'off';
            app.adapt_hit_ceil.Text = '';
            app.hit_ceil_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil_min.Editable = 'off';
            app.hit_ceil_min.Enable = 'off';
            app.hit_ceil_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil_max.Editable = 'off';
            app.hit_ceil_max.Enable = 'off';
            app.HitceilingdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitceilingdegEditFieldLabel.Enable = 'off';
            app.HitceilingdegEditFieldLabel.Text = 'Hit ceiling (deg):';
            app.hit_ceil = uieditfield(app.ParametersPanel, 'numeric');
            app.hit_ceil.Enable = 'off';
            app.HitwindowsEditFieldLabel = uilabel(app.ParametersPanel);
            app.HitwindowsEditFieldLabel.Text = 'Hit window (s):';
            app.hit_window = uieditfield(app.ParametersPanel, 'numeric');
            app.adapt_hold_time = uicheckbox(app.ParametersPanel);
            app.adapt_hold_time.Enable = 'off';
            app.adapt_hold_time.Text = '';
            app.hold_time_min = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time_min.Editable = 'off';
            app.hold_time_min.Enable = 'off';
            app.HoldtimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.HoldtimesEditFieldLabel.Enable = 'off';
            app.HoldtimesEditFieldLabel.Text = 'Hold time (s):';
            app.hold_time = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time.Editable = 'off';
            app.hold_time.Enable = 'off';
            app.SensorposcmLabel = uilabel(app.ParametersPanel);
            app.SensorposcmLabel.Text = 'Sensor pos (cm):';
            app.knob_pos = uieditfield(app.ParametersPanel, 'numeric');
            app.InitthreshdegEditFieldLabel = uilabel(app.ParametersPanel);
            app.InitthreshdegEditFieldLabel.Text = 'Init thresh (deg):';
            app.init_thresh = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxTrialsnumLabel = uilabel(app.ParametersPanel);
            app.MaxTrialsnumLabel.Text = 'Max Trials (num):';
            app.MaxTrialNum = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxTrialNum.Value = 100;
            app.hold_time_max = uieditfield(app.ParametersPanel, 'numeric');
            app.hold_time_max.Editable = 'off';
            app.hold_time_max.Enable = 'off';
            app.FailuretolerancedegLabel = uilabel(app.ParametersPanel);
            app.FailuretolerancedegLabel.Text = 'Failure tolerance (deg):';
            app.failure_tolerance = uieditfield(app.ParametersPanel, 'numeric');
            app.AdjustLeverBaselineButton = uibutton(app.ParametersPanel, 'push');
            app.AdjustLeverBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustLeverBaselineButtonPushed, true);
            app.AdjustLeverBaselineButton.Visible = 'off';
            app.AdjustLeverBaselineButton.Text = {'Adjust'; 'Lever Baseline'};
            app.STARTButton = uibutton(app.MainPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.MainPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.FEEDButton = uibutton(app.MainPanel, 'push');
            app.FEEDButton.ButtonPushedFcn = createCallbackFcn(app, @FEEDButtonPushed, true);
            app.FEEDButton.Enable = 'off';
            app.FEEDButton.Text = 'FEED';
            app.ConnectMotoTrakButton = uibutton(app.MainPanel, 'state');
            app.ConnectMotoTrakButton.ValueChangedFcn = createCallbackFcn(app, @ConnectMotoTrakButtonValueChanged, true);
            app.ConnectMotoTrakButton.Text = {'Connect'; 'MotoTrak'};
            app.RetractSensoratPosButton = uibutton(app.MainPanel, 'state');
            app.RetractSensoratPosButton.ValueChangedFcn = createCallbackFcn(app, @RetractSensoratPosButtonValueChanged, true);
            app.RetractSensoratPosButton.Enable = 'off';
            app.RetractSensoratPosButton.Text = {'Retract'; 'Sensor at Pos'};
            app.CalibrationfilelocationLabel = uilabel(app.MainPanel);
            app.CalibrationfilelocationLabel.Text = 'Calibration file location:';
            app.fileCalibName = uieditfield(app.MainPanel, 'text');
            app.fileCalibName.Value = 'Calibration2';
            app.ChangeButton = uibutton(app.MainPanel, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.PAUSECheckBox = uicheckbox(app.MainPanel);
            app.PAUSECheckBox.ValueChangedFcn = createCallbackFcn(app, @PAUSECheckBoxValueChanged, true);
            app.PAUSECheckBox.Enable = 'off';
            app.PAUSECheckBox.Text = 'PAUSE';
            app.StimulationPanel = uipanel(app.MainPanel);
            app.StimulationPanel.Title = 'Stimulation:';
            app.StimGroup = uibuttongroup(app.StimulationPanel);
            app.StimGroup.SelectionChangedFcn = createCallbackFcn(app, @StimGroupSelectionChanged, true);
            app.OFF = uiradiobutton(app.StimGroup);
            app.OFF.Text = 'OFF (-or- Control)';
            app.OFF.Value = true;
            app.RewardStim = uiradiobutton(app.StimGroup);
            app.RewardStim.Text = 'Reward Stim.';
            app.ManualStim = uibutton(app.StimGroup, 'push');
            app.ManualStim.ButtonPushedFcn = createCallbackFcn(app, @ManualStimButtonPushed2, true);
            app.ManualStim.Enable = 'off';
            app.ManualStim.Text = 'Stimulate';
            app.StimDurationmsEditFieldLabel = uilabel(app.StimGroup);
            app.StimDurationmsEditFieldLabel.Text = 'Stim Duration (ms)';
            app.StimDuration = uieditfield(app.StimGroup, 'numeric');
            app.StimDuration.Enable = 'off';
            app.StimDuration.Value = 5;
            app.RandStim = uiradiobutton(app.StimGroup);
            app.RandStim.Text = 'Random';
            app.StimeRateLabel = uilabel(app.StimGroup);
            app.StimeRateLabel.Text = 'Stime Rate (%)';
            app.StimeRate = uieditfield(app.StimGroup, 'numeric');
            app.StimeRate.Enable = 'off';
            app.StimeRate.Value = 5;
            app.tolerancedegLabel = uilabel(app.StimGroup);
            app.tolerancedegLabel.Text = 'tolerance (deg)';
            app.histo_tolerance = uieditfield(app.StimGroup, 'numeric');
            app.histo_tolerance.Enable = 'off';
            app.histo_tolerance.Value = 20;
            app.Browse_histoHT = uibutton(app.StimGroup, 'push');
            app.Browse_histoHT.ButtonPushedFcn = createCallbackFcn(app, @Browse_histoHTButtonPushed, true);
            app.Browse_histoHT.Enable = 'off';
            app.Browse_histoHT.Text = 'Browse';
            app.HistoricalHitThresholdLabel = uilabel(app.StimGroup);
            app.HistoricalHitThresholdLabel.Text = 'Historical Hit Threshold:';
            app.histoHT_dir = uieditfield(app.StimGroup, 'text');
            app.histoHT_dir.Enable = 'off';
            app.ManualStimButton = uiradiobutton(app.StimGroup);
            app.ManualStimButton.Text = 'Manual';
            app.Label = uilabel(app.MainPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.Lamp = uilamp(app.MainPanel);
            app.Lamp.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.moduleValueAxes = uiaxes(app.RightPanel);
            title(app.moduleValueAxes, 'Knob Rotation Angle')
            xlabel(app.moduleValueAxes, 'Time (s)')
            ylabel(app.moduleValueAxes, 'Angle (deg)')
            app.moduleValueAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.moduleValueAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.moduleValueAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PelletsdeliveredLabel = uilabel(app.RightPanel);
            app.PelletsdeliveredLabel.Text = 'Pellets delivered:';
            app.PelletsdeliveredCounterLabel = uilabel(app.RightPanel);
            app.PelletsdeliveredCounterLabel.Text = '0 (0.000 g)';
            app.TimeelapsedLabel = uilabel(app.RightPanel);
            app.TimeelapsedLabel.Text = 'Time elapsed:';
            app.TimeelapsedCounterLabel = uilabel(app.RightPanel);
            app.TimeelapsedCounterLabel.Text = '00:00:00';
            app.NumTrialsLabel = uilabel(app.RightPanel);
            app.NumTrialsLabel.Text = 'Num Trials: ';
            app.NumRewardsLabel = uilabel(app.RightPanel);
            app.NumRewardsLabel.Text = 'Num Rewards:';
            app.NumTrialsCounterLabel = uilabel(app.RightPanel);
            app.NumTrialsCounterLabel.Text = '0';
            app.NumRewardsCounterLabel = uilabel(app.RightPanel);
            app.NumRewardsCounterLabel.Text = '0';
            app.MedianPeakLabel = uilabel(app.RightPanel);
            app.MedianPeakLabel.Text = 'Median Peak:';
            app.MedianPeakValueLabel = uilabel(app.RightPanel);
            app.MedianPeakValueLabel.Text = '0';
            app.NumStimulationsLabel = uilabel(app.RightPanel);
            app.NumStimulationsLabel.Text = 'Num Stimulations:';
            app.NumStimulationsCounterLabel = uilabel(app.RightPanel);
            app.NumStimulationsCounterLabel.Text = '0';
            app.HistoricalHTValueLabel = uilabel(app.RightPanel);
            app.HistoricalHTValueLabel.Text = '0';
            app.HistoricalHTLabel = uilabel(app.RightPanel);
            app.HistoricalHTLabel.Text = 'Historical Hit Threshold:';
            % Show the figure after all components are created
            app.MotoTrakKnobUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motoTask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotoTrakKnobUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotoTrakKnobUIFigure)
        end
    end
end