classdef LeverCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalibratetheleverpullingforceLabel  matlab.ui.control.Label
        WeightgButtonGroup           matlab.ui.container.ButtonGroup
        CheckBox_5g                  matlab.ui.control.CheckBox
        CheckBox_10g                 matlab.ui.control.CheckBox
        CheckBox_25g                 matlab.ui.control.CheckBox
        CheckBox_50g                 matlab.ui.control.CheckBox
        AddButton                    matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        SensorOnButton               matlab.ui.control.Button
        CalibrationDataListBoxLabel  matlab.ui.control.Label
        CalibrationDataListBox       matlab.ui.control.ListBox
        Panel                        matlab.ui.container.Panel
        TotalWeighGaugeLabel         matlab.ui.control.Label
        TotalWeighGauge              matlab.ui.control.Gauge
        SensorinputValueGaugeLabel   matlab.ui.control.Label
        SensorinputValueGauge        matlab.ui.control.Gauge
        AddPairedValuesButton        matlab.ui.control.Button
        ActualWeightEditField        matlab.ui.control.NumericEditField
        ActualInputValue             matlab.ui.control.NumericEditField
        SensorOffButton              matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        moto_object; % object create with motoknob
        sum_weight=0;
        n_data=0;
        listboxItem;
        main_app; % mototrak app handle
    end
    
    properties (Access = public)
        calibration_data=[0 0]; % Description
        runCalibration=1; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.moto_object = Connect_MotoTrak;
           app.SensorinputValueGauge.Limits=[0 1000];
           app.TotalWeighGauge.Limits=[0 500];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.moto_object)
                fclose(app.moto_object.serialcon);
                app.moto_object = [];
            end
            delete(app)
            
        end

        % Callback function
        function CancelCalibrationButtonPushed(app, event)
         
        end

        % Button pushed function: SensorOnButton
        function SensorOnButtonPushed(app, event)
%             try
            app.runCalibration=1;
            while app.runCalibration
                app.SensorinputValueGauge.Value=app.moto_object.read_Pull();
                app.ActualInputValue.Value=app.moto_object.read_Pull();
                pause(0.01);
                drawnow;
            end
%             catch
%                errordlg('Put Sensor Off Next Time'); %Prevent error
%             end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            gramme=app.CheckBox_5g.Value.*5+app.CheckBox_10g.Value.*10+app.CheckBox_25g.Value.*25+app.CheckBox_50g.Value.*50;
            app.sum_weight=app.sum_weight+gramme;
            if app.sum_weight>app.TotalWeighGauge.Limits(2)
                app.TotalWeighGauge.Limits(2)=app.sum_weight.*2;
            end
            % Reset selection
            app.CheckBox_5g.Value=0;
            app.CheckBox_10g.Value=0;
            app.CheckBox_25g.Value=0;
            app.CheckBox_50g.Value=0;
            
            app.TotalWeighGauge.Value=app.sum_weight;
            app.ActualWeightEditField.Value=app.sum_weight;

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.sum_weight=0;
            app.TotalWeighGauge.Value=0;
            app.n_data=0;
            app.listboxItem={};
            app.CalibrationDataListBox.Items=app.listboxItem;
        end

        % Button pushed function: AddPairedValuesButton
        function AddPairedValuesButtonPushed(app, event)
            tic
            while toc<.5
            val=app.moto_object.read_Pull();
            end
            app.n_data=app.n_data+1;
            app.calibration_data(app.n_data,:)=[val app.sum_weight];
           
            app.SensorinputValueGauge.ScaleColors=[1 1 1]; 
            app.listboxItem{1,app.n_data}=strcat('[',num2str(val),32,num2str(app.sum_weight),']');
            app.CalibrationDataListBox.Items=app.listboxItem;
        end

        % Callback function
        function CalibrationDoneButtonPushed(app, event)
          
            
        end

        % Button pushed function: SensorOffButton
        function SensorOffButtonPushed(app, event)
            app.runCalibration=0;drawnow;pause(.1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           [coefficients,S]=polyfit(app.calibration_data(:,2),app.calibration_data(:,1),1); % (x,y,degre)
            %p(1)=pente;p(2)=ordonnée à l'origine
            coefficients(1) = 1/coefficients(1); % save the inverse of the slopeppee
            uisave('coefficients','variableCalibration');
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CalibratetheleverpullingforceLabel = uilabel(app.UIFigure);
            app.CalibratetheleverpullingforceLabel.Text = 'Calibrate the lever pulling force';
            app.WeightgButtonGroup = uibuttongroup(app.UIFigure);
            app.WeightgButtonGroup.Title = 'Weight (g)';
            app.CheckBox_5g = uicheckbox(app.WeightgButtonGroup);
            app.CheckBox_5g.Text = '5';
            app.CheckBox_10g = uicheckbox(app.WeightgButtonGroup);
            app.CheckBox_10g.Text = '10';
            app.CheckBox_25g = uicheckbox(app.WeightgButtonGroup);
            app.CheckBox_25g.Text = '25';
            app.CheckBox_50g = uicheckbox(app.WeightgButtonGroup);
            app.CheckBox_50g.Text = '50';
            app.AddButton = uibutton(app.WeightgButtonGroup, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ClearButton = uibutton(app.WeightgButtonGroup, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SensorOnButton = uibutton(app.UIFigure, 'push');
            app.SensorOnButton.ButtonPushedFcn = createCallbackFcn(app, @SensorOnButtonPushed, true);
            app.SensorOnButton.Text = 'Sensor On';
            app.CalibrationDataListBoxLabel = uilabel(app.UIFigure);
            app.CalibrationDataListBoxLabel.Text = 'Calibration Data';
            app.CalibrationDataListBox = uilistbox(app.UIFigure);
            app.CalibrationDataListBox.Items = {'[]'};
            app.CalibrationDataListBox.Value = '[]';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TotalWeighGaugeLabel = uilabel(app.Panel);
            app.TotalWeighGaugeLabel.Text = 'Total Weigh';
            app.TotalWeighGauge = uigauge(app.Panel, 'circular');
            app.SensorinputValueGaugeLabel = uilabel(app.Panel);
            app.SensorinputValueGaugeLabel.Text = 'Sensor input Value';
            app.SensorinputValueGauge = uigauge(app.Panel, 'circular');
            app.AddPairedValuesButton = uibutton(app.Panel, 'push');
            app.AddPairedValuesButton.ButtonPushedFcn = createCallbackFcn(app, @AddPairedValuesButtonPushed, true);
            app.AddPairedValuesButton.Text = 'Add Paired Values';
            app.ActualWeightEditField = uieditfield(app.Panel, 'numeric');
            app.ActualWeightEditField.Editable = 'off';
            app.ActualInputValue = uieditfield(app.Panel, 'numeric');
            app.ActualInputValue.Editable = 'off';
            app.SensorOffButton = uibutton(app.UIFigure, 'push');
            app.SensorOffButton.ButtonPushedFcn = createCallbackFcn(app, @SensorOffButtonPushed, true);
            app.SensorOffButton.Text = 'Sensor Off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeverCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end